classdef Maquina01MNED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        ResetButton                matlab.ui.control.Button
        ExecutarButton             matlab.ui.control.Button
        MtodosNumricosButtonGroup  matlab.ui.container.ButtonGroup
        Button3_3                  matlab.ui.control.ToggleButton
        Button3_2                  matlab.ui.control.ToggleButton
        Button2_2                  matlab.ui.control.ToggleButton
        Button_2                   matlab.ui.control.ToggleButton
        Button3                    matlab.ui.control.ToggleButton
        Button2                    matlab.ui.control.ToggleButton
        Button                     matlab.ui.control.ToggleButton
        DadosdoPVIPanel            matlab.ui.container.Panel
        nSpinner                   matlab.ui.control.Spinner
        nSpinnerLabel              matlab.ui.control.Label
        eY0                        matlab.ui.control.NumericEditField
        y0EditFieldLabel           matlab.ui.control.Label
        eB                         matlab.ui.control.NumericEditField
        bEditFieldLabel            matlab.ui.control.Label
        eA                         matlab.ui.control.NumericEditField
        aEditFieldLabel            matlab.ui.control.Label
        eF                         matlab.ui.control.EditField
        ftyEditFieldLabel          matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        UITable                    matlab.ui.control.Table
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExecutarButton
        function ExecutarButtonPushed(app, event)
            % Dados
            strF = app.eF.Value;
            f = @(t,y) eval(vectorize(strF));
            a = app.eA.Value;
            b = app.eB.Value;
            n = app.nSpinner.Value;
            y0 = app.eY0.Value;
    
            % solução exata do problema
            syms y(t);
            sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
            % solução exata do problema (expressão numérica)
            g = @(t) eval(vectorize(char(sExata)));
            
            % resultados e tabela de resultados
            [t,yEuler] = NEuler(f,a,b,n,y0);
            [~,yRK2] = NRK2(f,a,b,n,y0);
            
            yExata = g(t);
            erroEuler = abs(yExata-yEuler);
            erroRK2 = abs(yExata-yRK2);
            
            A = [t.',yExata.',yEuler.',yRK2.',erroEuler.',erroRK2.'];
            tabelaResultados = array2table(A,"VariableNames", ...
                               ["t","Exata","Euler","RK2","erroEuler","erroRK2"]);
            app.UITable.Data = tabelaResultados;
            
            % representação gráfica
            plot(app.UIAxes,t,yExata,"-bo");
            hold(app.UIAxes,"on");
            plot(app.UIAxes,t,yEuler,"-g*");
            plot(app.UIAxes,t,yRK2,"-r+");
            hold(app.UIAxes,"off");
            grid(app.UIAxes,"on");
            legend(app.UIAxes,'Exata','Euler',"RK2");
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {266, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosdoPVIPanel = uipanel(app.LeftPanel);
            app.DadosdoPVIPanel.Title = 'Dados do PVI';
            app.ftyEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.ftyEditFieldLabel.Text = 'f(t,y) = ';
            app.eF = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eF.Value = 'y+exp(3*t)';
            app.aEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eA.Value = 1;
            app.bEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.bEditFieldLabel.Text = 'b';
            app.eB = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eB.Value = 1.5;
            app.y0EditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.y0EditFieldLabel.Text = 'y0';
            app.eY0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eY0.Value = 2;
            app.nSpinnerLabel = uilabel(app.DadosdoPVIPanel);
            app.nSpinnerLabel.Text = 'n';
            app.nSpinner = uispinner(app.DadosdoPVIPanel);
            app.nSpinner.Limits = [1 Inf];
            app.nSpinner.Value = 1;
            app.MtodosNumricosButtonGroup = uibuttongroup(app.LeftPanel);
            app.MtodosNumricosButtonGroup.Title = 'Métodos Numéricos';
            app.Button = uitogglebutton(app.MtodosNumricosButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uitogglebutton(app.MtodosNumricosButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uitogglebutton(app.MtodosNumricosButtonGroup);
            app.Button3.Text = 'Button3';
            app.Button_2 = uitogglebutton(app.MtodosNumricosButtonGroup);
            app.Button_2.Text = 'Button';
            app.Button2_2 = uitogglebutton(app.MtodosNumricosButtonGroup);
            app.Button2_2.Text = 'Button2';
            app.Button3_2 = uitogglebutton(app.MtodosNumricosButtonGroup);
            app.Button3_2.Text = 'Button3';
            app.Button3_3 = uitogglebutton(app.MtodosNumricosButtonGroup);
            app.Button3_3.Text = 'Button3';
            app.ExecutarButton = uibutton(app.LeftPanel, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.Text = 'Reset';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Maquina01MNED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end